classdef minjeong_App_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        num_spot              matlab.ui.control.Label
        text39                matlab.ui.control.Label
        uipanel5              matlab.ui.container.Panel
        uipanel15             matlab.ui.container.Panel
        text54                matlab.ui.control.Label
        text43                matlab.ui.control.Label
        text42                matlab.ui.control.Label
        vehicle_Max_speed     matlab.ui.control.EditField
        Vehicle_Min_speed     matlab.ui.control.EditField
        text20                matlab.ui.control.Label
        text19                matlab.ui.control.Label
        Num_vehicle           matlab.ui.control.EditField
        text17                matlab.ui.control.Label
        uibuttongroup2        matlab.ui.container.ButtonGroup
        destination           matlab.ui.control.EditField
        source                matlab.ui.control.EditField
        text48                matlab.ui.control.Label
        text49                matlab.ui.control.Label
        uipanel6              matlab.ui.container.Panel
        text58                matlab.ui.control.Label
        text57                matlab.ui.control.Label
        text56                matlab.ui.control.Label
        edit16                matlab.ui.control.EditField
        edit15                matlab.ui.control.EditField
        edit14                matlab.ui.control.EditField
        text23                matlab.ui.control.Label
        text22                matlab.ui.control.Label
        text21                matlab.ui.control.Label
        car_create            matlab.ui.control.Button
        uipanel4              matlab.ui.container.Panel
        uipanel3              matlab.ui.container.Panel
        text53                matlab.ui.control.Label
        text52                matlab.ui.control.Label
        text12                matlab.ui.control.Label
        text13                matlab.ui.control.Label
        text14                matlab.ui.control.Label
        text15                matlab.ui.control.Label
        cell_partition_ratio  matlab.ui.control.EditField
        sub_vertical_ratio    matlab.ui.control.EditField
        sub_horizontal_ratio  matlab.ui.control.EditField
        sub_both_ratio        matlab.ui.control.EditField
        uipanel2              matlab.ui.container.Panel
        text41                matlab.ui.control.Label
        text40                matlab.ui.control.Label
        uipanel1              matlab.ui.container.Panel
        uipanel14             matlab.ui.container.Panel
        text51                matlab.ui.control.Label
        text50                matlab.ui.control.Label
        lane_range_to         matlab.ui.control.EditField
        text8                 matlab.ui.control.Label
        from                  matlab.ui.control.Label
        text10                matlab.ui.control.Label
        to                    matlab.ui.control.Label
        text2                 matlab.ui.control.Label
        text3                 matlab.ui.control.Label
        Size_X                matlab.ui.control.EditField
        Size_Y                matlab.ui.control.EditField
        text4                 matlab.ui.control.Label
        text5                 matlab.ui.control.Label
        num_ver               matlab.ui.control.EditField
        num_hor               matlab.ui.control.EditField
        create                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function minjeong_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to minjeong (see VARARGIN)
            
            % Choose default command line output for minjeong
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: Num_vehicle
        function Num_vehicle_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Num_vehicle (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Num_vehicle as text
            %        str2double(get(hObject,'String')) returns contents of Num_vehicle as a double
            
            global Num_vehicle
            Num_vehicle = get(handles.Num_vehicle,'String');
            Num_vehicle = str2double(Num_vehicle);
        end

        % Value changed function: Size_X
        function Size_X_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Size_X (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Size_X as text
            %        str2double(get(hObject,'String')) returns contents of Size_X as a double
            
            global X_Size_Road
            X_Size_Road = get(handles.Size_X,'String');
            X_Size_Road = str2double(X_Size_Road);
        end

        % Value changed function: Size_Y
        function Size_Y_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Size_Y (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Size_Y as text
            %        str2double(get(hObject,'String')) returns contents of Size_Y as a double
            
            global Y_Size_Road
            Y_Size_Road = get(handles.Size_Y,'String');
            Y_Size_Road = str2double(Y_Size_Road);
        end

        % Value changed function: Vehicle_Min_speed
        function Vehicle_Min_speed_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global Vehicle_Min_speed
            Vehicle_Min_speed = get(handles.Vehicle_Min_speed,'String');
            Vehicle_Min_speed = str2double(Vehicle_Min_speed);
        end

        % Button pushed function: car_create
        function car_create_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            run vehicle
        end

        % Value changed function: cell_partition_ratio
        function cell_partition_ratio_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cell_partition_ratio (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of cell_partition_ratio as text
            %        str2double(get(hObject,'String')) returns contents of cell_partition_ratio as a double
            
            global cell_part_ratio
            
            cell_part_ratio = get(handles.cell_partition_ratio,'String');
            cell_part_ratio = str2double(cell_part_ratio);
        end

        % Button pushed function: create
        function create_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to create (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            run road
        end

        % Value changed function: destination
        function destination_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to destination (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of destination as text
            %        str2double(get(hObject,'String')) returns contents of destination as a double
            global destination
            destination = get(handles.destination,'String');
            destination = str2double(destination);
        end

        % Value changed function: lane_range_to
        function lane_range_to_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to lane_range_to (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of lane_range_to as text
            %        str2double(get(hObject,'String')) returns contents of lane_range_to as a double
            
            global lane_to
            
            lane_to = get(handles.lane_range_to,'String');
            lane_to = str2double(lane_to);
        end

        % Value changed function: num_hor
        function num_hor_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to num_hor (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of num_hor as text
            %        str2double(get(hObject,'String')) returns contents of num_hor as a double
            
            global Y_Size_Road
            global Num_horizontal
            global hor_interval
            global ver_lane
            global hor_lane
            
            Num_horizontal = get(handles.num_hor,'String');
            Num_horizontal = str2double(Num_horizontal);
            
            %%%%%%%%%%%%%%%%%%%%%%% 차선 범위 결정 %%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            hor_interval = Y_Size_Road / ( Num_horizontal -1 );
            hor_lane = hor_interval / (2*7);
            
            if(ver_lane > hor_lane)
                ver_lane = fix(ver_lane);
                if(ver_lane > 14)
                    set(handles.to,'String',14);
                elseif(mod(ver_lane,2) == 1)
                    set(handles.to,'String',ver_lane - 1);
                else
                    set(handles.to,'String',ver_lane);
                end
            else
                hor_lane = fix(hor_lane);
                if(hor_lane > 14)
                    set(handles.to,'String',14);
                elseif(mod(hor_lane,2) == 1)
                    set(handles.to,'String',hor_lane - 1);
                else
                    set(handles.to,'String',hor_lane);
                end
            end
        end

        % Value changed function: num_ver
        function num_ver_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to num_ver (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of num_ver as text
            %        str2double(get(hObject,'String')) returns contents of num_ver as a double
            
            global X_Size_Road
            global Num_vertical
            global ver_interval
            global ver_lane
            
            Num_vertical = get(handles.num_ver,'String');
            Num_vertical = str2double(Num_vertical);
            
            ver_interval = X_Size_Road / ( Num_vertical -1 );
            ver_lane = ver_interval / (2*7);
        end

        % Value changed function: source
        function source_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to source (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of source as text
            %        str2double(get(hObject,'String')) returns contents of source as a double
            global source
            source = get(handles.source,'String');
            source = str2double(source);
        end

        % Value changed function: sub_both_ratio
        function sub_both_ratio_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sub_both_ratio (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of sub_both_ratio as text
            %        str2double(get(hObject,'String')) returns contents of sub_both_ratio as a double
            
            global sub_bo_ratio
            
            sub_bo_ratio = get(handles.sub_both_ratio,'String');
            sub_bo_ratio = str2double(sub_bo_ratio);
        end

        % Value changed function: sub_horizontal_ratio
        function sub_horizontal_ratio_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sub_horizontal_ratio (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of sub_horizontal_ratio as text
            %        str2double(get(hObject,'String')) returns contents of sub_horizontal_ratio as a double
            
            global sub_hor_ratio
            
            sub_hor_ratio = get(handles.sub_horizontal_ratio,'String');
            sub_hor_ratio = str2double(sub_hor_ratio);
        end

        % Value changed function: sub_vertical_ratio
        function sub_vertical_ratio_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sub_vertical_ratio (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of sub_vertical_ratio as text
            %        str2double(get(hObject,'String')) returns contents of sub_vertical_ratio as a double
            
            global sub_ver_ratio
            
            sub_ver_ratio = get(handles.sub_vertical_ratio,'String');
            sub_ver_ratio = str2double(sub_ver_ratio);
        end

        % Value changed function: vehicle_Max_speed
        function vehicle_Max_speed_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global Vehicle_Max_speed
            Vehicle_Max_speed = get(handles.vehicle_Max_speed,'String');
            Vehicle_Max_speed = str2double(Vehicle_Max_speed);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'minjeong';
            app.figure1.Tag = 'figure1';
            app.create = uibutton(app.figure1, 'push');
            app.create.ButtonPushedFcn = createCallbackFcn(app, @create_Callback, true);
            app.create.Tag = 'create';
            app.create.Text = 'Road & Car Model';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'Road Components';
            app.uipanel4.Tag = 'uipanel4';
            app.uipanel2 = uipanel(app.uipanel4);
            app.uipanel2.Title = 'Main Street';
            app.uipanel2.Tag = 'uipanel2';
            app.num_hor = uieditfield(app.uipanel2, 'text');
            app.num_hor.ValueChangedFcn = createCallbackFcn(app, @num_hor_Callback, true);
            app.num_hor.Tag = 'num_hor';
            app.num_ver = uieditfield(app.uipanel2, 'text');
            app.num_ver.ValueChangedFcn = createCallbackFcn(app, @num_ver_Callback, true);
            app.num_ver.Tag = 'num_ver';
            app.text5 = uilabel(app.uipanel2);
            app.text5.Tag = 'text5';
            app.text5.Text = '# of horizontal roads';
            app.text4 = uilabel(app.uipanel2);
            app.text4.Tag = 'text4';
            app.text4.Text = '# of vertical roads';
            app.Size_Y = uieditfield(app.uipanel2, 'text');
            app.Size_Y.ValueChangedFcn = createCallbackFcn(app, @Size_Y_Callback, true);
            app.Size_Y.Tag = 'Size_Y';
            app.Size_X = uieditfield(app.uipanel2, 'text');
            app.Size_X.ValueChangedFcn = createCallbackFcn(app, @Size_X_Callback, true);
            app.Size_X.Tag = 'Size_X';
            app.text3 = uilabel(app.uipanel2);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Y Size of map';
            app.text2 = uilabel(app.uipanel2);
            app.text2.Tag = 'text2';
            app.text2.Text = 'X Size of map';
            app.uipanel1 = uipanel(app.uipanel2);
            app.uipanel1.Title = 'Lane Range';
            app.uipanel1.Tag = 'uipanel1';
            app.to = uilabel(app.uipanel1);
            app.to.Tag = 'to';
            app.to.Text = 'null';
            app.text10 = uilabel(app.uipanel1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'and';
            app.from = uilabel(app.uipanel1);
            app.from.Tag = 'from';
            app.from.Text = '2';
            app.text8 = uilabel(app.uipanel1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'between';
            app.lane_range_to = uieditfield(app.uipanel1, 'text');
            app.lane_range_to.ValueChangedFcn = createCallbackFcn(app, @lane_range_to_Callback, true);
            app.lane_range_to.Tag = 'lane_range_to';
            app.text50 = uilabel(app.uipanel1);
            app.text50.Tag = 'text50';
            app.text50.Text = 'The number of lanes can be randomly generated';
            app.text51 = uilabel(app.uipanel1);
            app.text51.Tag = 'text51';
            app.text51.Text = 'Enter the end number of lane range';
            app.uipanel14 = uipanel(app.uipanel1);
            app.uipanel14.Title = 'Random Generation Method';
            app.uipanel14.Tag = 'uipanel14';
            app.text40 = uilabel(app.uipanel2);
            app.text40.Tag = 'text40';
            app.text40.Text = 'm';
            app.text41 = uilabel(app.uipanel2);
            app.text41.Tag = 'text41';
            app.text41.Text = 'm';
            app.uipanel3 = uipanel(app.uipanel4);
            app.uipanel3.Title = 'Sub Street';
            app.uipanel3.Tag = 'uipanel3';
            app.sub_both_ratio = uieditfield(app.uipanel3, 'text');
            app.sub_both_ratio.ValueChangedFcn = createCallbackFcn(app, @sub_both_ratio_Callback, true);
            app.sub_both_ratio.Tag = 'sub_both_ratio';
            app.sub_horizontal_ratio = uieditfield(app.uipanel3, 'text');
            app.sub_horizontal_ratio.ValueChangedFcn = createCallbackFcn(app, @sub_horizontal_ratio_Callback, true);
            app.sub_horizontal_ratio.Tag = 'sub_horizontal_ratio';
            app.sub_vertical_ratio = uieditfield(app.uipanel3, 'text');
            app.sub_vertical_ratio.ValueChangedFcn = createCallbackFcn(app, @sub_vertical_ratio_Callback, true);
            app.sub_vertical_ratio.Tag = 'sub_vertical_ratio';
            app.cell_partition_ratio = uieditfield(app.uipanel3, 'text');
            app.cell_partition_ratio.ValueChangedFcn = createCallbackFcn(app, @cell_partition_ratio_Callback, true);
            app.cell_partition_ratio.Tag = 'cell_partition_ratio';
            app.text15 = uilabel(app.uipanel3);
            app.text15.Tag = 'text15';
            app.text15.Text = 'The ratio of both sub street';
            app.text14 = uilabel(app.uipanel3);
            app.text14.Tag = 'text14';
            app.text14.Text = 'The ratio of horizontal sub street';
            app.text13 = uilabel(app.uipanel3);
            app.text13.Tag = 'text13';
            app.text13.Text = 'The ratio of Vertical sub street';
            app.text12 = uilabel(app.uipanel3);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Cell partition Ratio';
            app.text52 = uilabel(app.uipanel3);
            app.text52.Tag = 'text52';
            app.text52.Text = '- Cell은 Main Street으로 이루어진 White Block Area';
            app.text53 = uilabel(app.uipanel3);
            app.text53.Tag = 'text53';
            app.text53.Text = '- Sub street이 발생할 확률';
            app.car_create = uibutton(app.figure1, 'push');
            app.car_create.ButtonPushedFcn = createCallbackFcn(app, @car_create_Callback, true);
            app.car_create.Tag = 'car_create';
            app.car_create.Text = 'Routing Path Create';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Title = 'Vehicle Components';
            app.uipanel5.Tag = 'uipanel5';
            app.uipanel6 = uipanel(app.uipanel5);
            app.uipanel6.Title = 'Moving direcion ratio at intersection';
            app.uipanel6.Tag = 'uipanel6';
            app.text21 = uilabel(app.uipanel6);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Straight';
            app.text22 = uilabel(app.uipanel6);
            app.text22.Tag = 'text22';
            app.text22.Text = 'Left';
            app.text23 = uilabel(app.uipanel6);
            app.text23.Tag = 'text23';
            app.text23.Text = 'Right';
            app.edit14 = uieditfield(app.uipanel6, 'text');
            app.edit14.Tag = 'edit14';
            app.edit15 = uieditfield(app.uipanel6, 'text');
            app.edit15.Tag = 'edit15';
            app.edit16 = uieditfield(app.uipanel6, 'text');
            app.edit16.Tag = 'edit16';
            app.text56 = uilabel(app.uipanel6);
            app.text56.Tag = 'text56';
            app.text56.Text = '%';
            app.text57 = uilabel(app.uipanel6);
            app.text57.Tag = 'text57';
            app.text57.Text = '%';
            app.text58 = uilabel(app.uipanel6);
            app.text58.Tag = 'text58';
            app.text58.Text = '%';
            app.uibuttongroup2 = uibuttongroup(app.uipanel5);
            app.uibuttongroup2.Title = 'Source & Destination''s vehicle ID';
            app.uibuttongroup2.Tag = 'uibuttongroup2';
            app.text49 = uilabel(app.uibuttongroup2);
            app.text49.Tag = 'text49';
            app.text49.Text = 'Source';
            app.text48 = uilabel(app.uibuttongroup2);
            app.text48.Tag = 'text48';
            app.text48.Text = 'Destination';
            app.source = uieditfield(app.uibuttongroup2, 'text');
            app.source.ValueChangedFcn = createCallbackFcn(app, @source_Callback, true);
            app.source.Tag = 'source';
            app.destination = uieditfield(app.uibuttongroup2, 'text');
            app.destination.ValueChangedFcn = createCallbackFcn(app, @destination_Callback, true);
            app.destination.Tag = 'destination';
            app.uipanel15 = uipanel(app.uipanel5);
            app.uipanel15.Title = 'Vehicle Number & Speed';
            app.uipanel15.Tag = 'uipanel15';
            app.text17 = uilabel(app.uipanel15);
            app.text17.Tag = 'text17';
            app.text17.Text = '# of vehicles';
            app.Num_vehicle = uieditfield(app.uipanel15, 'text');
            app.Num_vehicle.ValueChangedFcn = createCallbackFcn(app, @Num_vehicle_Callback, true);
            app.Num_vehicle.Tag = 'Num_vehicle';
            app.text19 = uilabel(app.uipanel15);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Max Speed';
            app.text20 = uilabel(app.uipanel15);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Min speed';
            app.Vehicle_Min_speed = uieditfield(app.uipanel15, 'text');
            app.Vehicle_Min_speed.ValueChangedFcn = createCallbackFcn(app, @Vehicle_Min_speed_Callback, true);
            app.Vehicle_Min_speed.Tag = 'Vehicle_Min_speed';
            app.vehicle_Max_speed = uieditfield(app.uipanel15, 'text');
            app.vehicle_Max_speed.ValueChangedFcn = createCallbackFcn(app, @vehicle_Max_speed_Callback, true);
            app.vehicle_Max_speed.Tag = 'vehicle_Max_speed';
            app.text42 = uilabel(app.uipanel15);
            app.text42.Tag = 'text42';
            app.text42.Text = 'm/s';
            app.text43 = uilabel(app.uipanel15);
            app.text43.Tag = 'text43';
            app.text43.Text = 'm/s';
            app.text54 = uilabel(app.uipanel15);
            app.text54.Tag = 'text54';
            app.text54.Text = '( Each vehicle moves between Max and Min speed )';
            app.text39 = uilabel(app.figure1);
            app.text39.Tag = 'text39';
            app.text39.Text = 'Vehicle Ad-hoc Network Simulator';
            app.num_spot = uilabel(app.figure1);
            app.num_spot.Tag = 'num_spot';
            app.num_spot.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = minjeong_App_1(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)minjeong_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end